classdef unified_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        RecordTab                 matlab.ui.container.Tab
        M_ModeDropDown            matlab.ui.control.DropDown
        ModeDropDownLabel         matlab.ui.control.Label
        M_TimeScaleDropDown       matlab.ui.control.DropDown
        TimeScaleDropDownLabel    matlab.ui.control.Label
        M_SAVEButton_2            matlab.ui.control.Button
        M_RECORDButton            matlab.ui.control.Button
        M_SAVEButton              matlab.ui.control.Button
        M_RUNSTOPButton           matlab.ui.control.Button
        M_In_Slider               matlab.ui.control.RangeSlider
        M_Out_Slider              matlab.ui.control.RangeSlider
        M_PortsDropDown           matlab.ui.control.DropDown
        PortsDropDownLabel        matlab.ui.control.Label
        PPmVLabel_3               matlab.ui.control.Label
        AvgmVLabel_3              matlab.ui.control.Label
        MinmVLabel_3              matlab.ui.control.Label
        MaxmVLabel_3              matlab.ui.control.Label
        M_Out_AvgmVEditField      matlab.ui.control.NumericEditField
        M_Out_MaxmVEditField      matlab.ui.control.NumericEditField
        M_Out_MinmVEditField      matlab.ui.control.NumericEditField
        M_Out_PPAmpmVEditField    matlab.ui.control.NumericEditField
        OutputLabel               matlab.ui.control.Label
        PPmVLabel_2               matlab.ui.control.Label
        AvgmVLabel_2              matlab.ui.control.Label
        MinmVLabel_2              matlab.ui.control.Label
        MaxmVLabel_2              matlab.ui.control.Label
        M_In_AvgmVEditField       matlab.ui.control.NumericEditField
        M_In_MaxmVEditField       matlab.ui.control.NumericEditField
        M_In_MinmVEditField       matlab.ui.control.NumericEditField
        M_In_PPAmpmVEditField     matlab.ui.control.NumericEditField
        InputLabel                matlab.ui.control.Label
        M_RecordLamp              matlab.ui.control.Lamp
        M_Out_Plot                matlab.ui.control.UIAxes
        M_In_Plot                 matlab.ui.control.UIAxes
        UploaderTab               matlab.ui.container.Tab
        UL_ClearButton            matlab.ui.control.Button
        UL_Slider                 matlab.ui.control.RangeSlider
        StdmsLabel                matlab.ui.control.Label
        AvgmsLabel                matlab.ui.control.Label
        MinmsLabel                matlab.ui.control.Label
        MaxmsLabel                matlab.ui.control.Label
        UL_MaxmsEditField         matlab.ui.control.NumericEditField
        UL_AvgmsEditField         matlab.ui.control.NumericEditField
        UL_StdmsEditField         matlab.ui.control.NumericEditField
        UL_MinmsEditField         matlab.ui.control.NumericEditField
        IntervalLabel             matlab.ui.control.Label
        PPmVLabel                 matlab.ui.control.Label
        AvgmVLabel                matlab.ui.control.Label
        MinmVLabel                matlab.ui.control.Label
        MaxmVLabel                matlab.ui.control.Label
        UL_AvgmVEditField         matlab.ui.control.NumericEditField
        UL_MaxmVEditField         matlab.ui.control.NumericEditField
        UL_MinmVEditField         matlab.ui.control.NumericEditField
        UL_PPAmpmVEditField       matlab.ui.control.NumericEditField
        AmplitudeLabel            matlab.ui.control.Label
        DurationLabel             matlab.ui.control.Label
        FsHzLabel                 matlab.ui.control.Label
        UL_SamplingRateEditField  matlab.ui.control.NumericEditField
        SamplingRateLabel         matlab.ui.control.Label
        tmsLabel_2                matlab.ui.control.Label
        UL_DurationEditField      matlab.ui.control.NumericEditField
        UL_FlushButton            matlab.ui.control.Button
        UL_SearchButton           matlab.ui.control.Button
        UL_UploadButton           matlab.ui.control.Button
        UL_FileEditField          matlab.ui.control.EditField
        FileEditFieldLabel        matlab.ui.control.Label
        UL_Plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device % The Seeeduino device

        default_port_message='Select a port'

        Playback_tBuf=[]
        Playback_vBuf=[]

        dataTimer 
        plotTimer 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Monitor
        M_In_vBuf = [] 
        M_In_tBuf = [] 

        M_Out_vBuf = []
        M_Out_tBuf = []

        Save_vBuf = []
        Save_tBuf = []
        
        M_In_Enable
        M_Out_Enable

        M_In_srt_vline
        M_In_end_vline
        M_In_shadedArea

        M_Out_srt_vline
        M_Out_end_vline
        M_Out_shadedArea
        
        showLength = 1000 % Number of points to show on the plot

        % RUN/STOP Btn Click
        isRunning logical = true % Flag to updating the plot

        % RECORD Btn
        isRecording logical = false

        % Zoom
        Record_tTickSize
        Record_tTickNumDisp=10

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% Uploader
        UL_vBuf = [] 
        UL_tBuf = []

        UL_srt_vline
        UL_end_vline
        UL_shadedArea
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    
    methods (Access = private)
        %% Thread
        function acquireData(app)
            if ~isempty(app.device)
                while app.device.NumBytesAvailable > 0
                    dataLine = readline(app.device);
                    dataParts = strsplit(dataLine, ',');
                    if length(dataParts) ~= 2
                        error('dataline is wrong, not [timestamps, volts]')
                    end

                    millis = str2double(dataParts{1});
                    numericValue = str2double(dataParts{2});
                    if ~isnan(numericValue) && ~isnan(millis)
                        elapsedMillisec = millis;
                        if app.isRunning
                            app.M_In_vBuf = [app.M_In_vBuf, numericValue];
                            app.M_In_tBuf = [app.M_In_tBuf, elapsedMillisec];
                        end

                        if app.isRecording
                            app.Save_vBuf=[app.Save_vBuf numericValue];
                            app.Save_tBuf=[app.Save_tBuf elapsedMillisec];
                        end
                        
                        if length(app.M_In_vBuf) > app.showLength
                            app.M_In_vBuf = app.M_In_vBuf(end-app.showLength+1:end);
                            app.M_In_tBuf = app.M_In_tBuf(end-app.showLength+1:end);
                        end
                    end
                end
            end
        end
        
        function updatePlot(app)
            if(app.isRunning && ~isempty(app.M_In_tBuf))
                app.M_plot();

                % Edit the features
                app.Record_tmsEditField.Value=min(app.M_In_vBuf);
                app.Record_MaxmVEditField.Value=max(app.M_In_vBuf);
                app.Record_PPAmpmVEditField.Value=app.Record_MaxmVEditField.Value-app.Record_tmsEditField.Value;
                app.Record_AvgmVEditField.Value=mean(app.M_In_vBuf);
                app.Record_FreqHzEditField.Value=app.DomFreq(app.M_In_tBuf,app.M_In_vBuf);
            end
        end
        
        
        %%
        function M_plot(app)
            % Plot the data
                plot(app.M_In_Plot, app.M_In_tBuf, app.M_In_vBuf);
                
                % Ensure there's data to work with
                if ~isempty(app.M_In_tBuf)
                    % Calculate the right limit of the x-axis based on the latest timestamp
                    lastTimestamp = app.M_In_tBuf(end);
                    
                    % Calculate the right limit of the x-axis
                    rtlim = lastTimestamp + app.Record_tOffsetByTick * app.Record_tTickSize;
                    
                    % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
                    ltlim = rtlim - app.Record_tTickNumDisp * app.Record_tTickSize;
                    
                    % Set the x-axis limits
                    xlim(app.M_In_Plot, [ltlim, rtlim]);
                end
                
                % Update the plot
                drawnow;
        end

        % function UL_AdjustWin(app)
        %     % Plot the data
        % 
        % 
        %     % Variables to hold the indices of data within the visible range
        %     visibleIndices = [];
        % 
        %     % Ensure there's data to work with
        %     if ~isempty(app.UL_tBuf)
        %         % Calculate the right limit of the x-axis based on the latest timestamp
        %         app.UL_ct=round(app.UL_ct/app.UL_tTickSize)*app.UL_tTickSize;
        % 
        %         lastT = app.UL_tBuf(end);
        %         % Calculate the right limit of the x-axis
        %         rtlim = lastT - app.UL_ct +app.UL_tHalfWinByTick*app.UL_tTickSize;
        % 
        %         % Calculate the left limit of the x-axis based on the number of ticks to display and tick size
        %         ltlim = rtlim - 2 * app.UL_tHalfWinByTick * app.UL_tTickSize;
        % 
        % 
        %         % Set the x-axis limits
        %         xlim(app.UL_Plot, [ltlim rtlim]);
        % 
        %         % Find indices of the data within the visible range
        %         visibleIndices = find(app.UL_tBuf >= ltlim & app.UL_tBuf <= rtlim);
        %     end
        % 
        %     if ~isempty(app.UL_vBuf)
        %         app.UL_cv=round(app.UL_cv/app.UL_vTickSize);
        % 
        %         uvlim=app.UL_cv + app.UL_vHalfWinByTick *app.UL_vTickSize;
        %         dvlim=app.UL_cv - app.UL_vHalfWinByTick *app.UL_vTickSize;
        %         ylim(app.UL_Plot, [dvlim uvlim]);
        %     end
        % 
        %     % Update the plot
        %     drawnow;
        % 
        %     % % Only update statistics if there are visible indices
        %     % if ~isempty(visibleIndices)
        %     %     % Calculate statistics for visible data
        %     %     visibleVoltages = app.UL_vBuf(visibleIndices);
        %     %     app.UL_MinmVEditField.Value = min(visibleVoltages);
        %     %     app.UL_MaxmVEditField.Value = max(visibleVoltages);
        %     %     app.UL_PPAmpmVEditField.Value = app.UL_MaxmVEditField.Value - app.UL_MinmVEditField.Value;
        %     %     app.UL_AvgmVEditField.Value = mean(visibleVoltages);
        %     % 
        %     %     % Assuming app.DomFreq calculates dominant frequency
        %     %     app.Playback_FreqHzEditField.Value = app.DomFreq(app.UL_tBuf(visibleIndices), visibleVoltages);
        %     % else
        %     %     % Handle case where no data is visible
        %     %     app.UL_MinmVEditField.Value = NaN;
        %     %     app.UL_MaxmVEditField.Value = NaN;
        %     %     app.UL_PPAmpmVEditField.Value = NaN;
        %     %     app.UL_AvgmVEditField.Value = NaN;
        %     %     app.Playback_FreqHzEditField.Value = NaN;
        %     % end
        % end
        
        %% CSV
        function saveCSV(app, timestamps, volts)
            % Open a file save dialog allowing the user to specify a CSV file
            [file, path] = uiputfile({'*.csv','CSV Files (*.csv)'}, 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User clicked Cancel.');
            else
                % Construct the full path where the file will be saved
                fullPath = fullfile(path, file);
                disp(['User selected ', fullPath, ' for saving.']);
                
                % Create a table from the timestamps and volts arrays
                T = table(timestamps(:), volts(:), 'VariableNames', {'Timestamps', 'Volts'});
                
                % Write the table to a CSV file
                writetable(T, fullPath);
                disp(['Data saved to ', fullPath]);
            end
        end

        function [time, volt] = readCSV(app, csv_path)
            % Define keywords to identify the time and voltage columns
            TIME_INDICATORS = {'time', 'sec', 'stamp'};
            VOLT_INDICATORS = {'volt', 'amp', 'meas'};
            
            % Read the CSV file. This assumes the file has a header row.
            opts = detectImportOptions(csv_path);
            data = readtable(csv_path, opts);
            
            % Initialize output variables
            time = [];
            volt = [];
            
            % Loop over all column names to find matches for time and voltage
            for i = 1:length(opts.VariableNames)
                varName = lower(opts.VariableNames{i});  % Convert to lowercase to ensure case-insensitivity
                % Check if the column name contains any of the time indicators
                if any(contains(varName, TIME_INDICATORS))
                    time = data{:, i};
                % Check if the column name contains any of the voltage indicators
                elseif any(contains(varName, VOLT_INDICATORS))
                    volt = -data{:, i};
                end
            end
            
            % Check if time or volt columns were found
            if isempty(time)
                error('Time column not found in the CSV file.');
            end
            if isempty(volt)
                error('Voltage column not found in the CSV file.');
            end
        end

        %% Plot Selection
        function resetMarks(app)
            % Input plot reset
            if isgraphics(app.M_In_srt_vline, 'line')
                delete(app.M_In_srt_vline);
            end
            if isgraphics(app.M_In_end_vline, 'line')
                delete(app.M_In_end_vline);
            end
            if isgraphics(app.M_In_shadedArea, 'patch')
                delete(app.M_In_shadedArea);
            end
            % Output plot reset
            if isgraphics(app.M_Out_srt_vline, 'line')
                delete(app.M_Out_srt_vline);
            end
            if isgraphics(app.M_Out_end_vline, 'line')
                delete(app.M_Out_end_vline);
            end
            if isgraphics(app.M_Out_shadedArea, 'patch')
                delete(app.M_Out_shadedArea);
            end
            if isgraphics(app.UL_srt_vline, 'line')
                delete(app.UL_srt_vline);
            end
            if isgraphics(app.UL_end_vline, 'line')
                delete(app.UL_end_vline);
            end
            if isgraphics(app.UL_shadedArea, 'patch')
                delete(app.UL_shadedArea);
            end
        end

        function UL_NumUpdate(app, ts, vs)
            sel_empty=app.isValid_TVpair(ts, vs);

            if ~sel_empty
                duration = ts(end)-ts(1);
                intervals = diff(ts);
                avgInterval = duration/length(ts);


                % Sampling Rate
                app.UL_SamplingRateEditField.Value = duration/length(intervals);

                % Duration
                app.UL_DurationEditField.Value = duration;

                % Amplitude
                app.UL_MinmVEditField.Value = min(vs);
                app.UL_MaxmVEditField.Value = max(vs);
                app.UL_AvgmVEditField.Value = mean(vs);
                app.UL_PPAmpmVEditField.Value = max(vs)-min(vs);

                % Interval
                app.UL_MinmsEditField.Value = min(intervals);
                app.UL_MaxmsEditField.Value = max(intervals);
                app.UL_AvgmsEditField.Value = avgInterval;
                app.UL_StdmsEditField.Value = std(intervals);
                
            else
                tot_empty = app.isValid_TVpair(app.UL_tBuf, app.UL_vBuf);
                if ~tot_empty
                    app.UL_NumUpdate(app.UL_tBuf, app.UL_vBuf)
                else
                    app.UL_ZeroNum();
                end
            end
        end

        function UL_ZeroNum(app)
            % Sampling Rate
            app.UL_SamplingRateEditField.Value = 0;

            % Duration
            app.UL_DurationEditField.Value = 0;

            % Amplitude
            app.UL_MinmVEditField.Value = 0;
            app.UL_MaxmVEditField.Value = 0;
            app.UL_AvgmVEditField.Value = 0;
            app.UL_PPAmpmVEditField.Value = 0;

            % Interval
            app.UL_MinmsEditField.Value = 0;
            app.UL_MaxmsEditField.Value = 0;
            app.UL_AvgmsEditField.Value = 0;
            app.UL_StdmsEditField.Value = 0;
        end
        
        function M_NumUpdate(app, in_vs, out_vs) %Update all the EditField in Monitor tab
            if ~app.M_In_Enable & ~isempty(in_vs)
                app.M_In_MaxmVEditField.Value=max(in_vs);
                app.M_In_MinmVEditField.Value=min(in_vs);
                app.M_In_AvgmVEditField.Value=mean(in_vs);
                app.M_In_PPAmpmVEditField.Value=max(in_vs)-min(in_vs);
            else
                app.M_In_MaxmVEditField.Value=0;
                app.M_In_MinmVEditField.Value=0;
                app.M_In_AvgmVEditField.Value=0;
                app.M_In_PPAmpmVEditField.Value=0;
            end
            if ~app.M_Out_Enable & ~isempty(out_vs)
                app.M_Out_MaxmVEditField.Value=max(out_vs);
                app.M_Out_MinmVEditField.Value=min(out_vs);
                app.M_Out_AvgmVEditField.Value=mean(out_vs);
                app.M_Out_PPAmpmVEditField.Value=max(out_vs)-min(out_vs);
            else
                app.M_Out_MaxmVEditField.Value=0;
                app.M_Out_MinmVEditField.Value=0;
                app.M_Out_AvgmVEditField.Value=0;
                app.M_Out_PPAmpmVEditField.Value=0;
            end
        end

        function [srt_vline, end_vline, shadedArea,sel_ts, sel_vs]=drawMarks(app,  per1, per2, plot, ts, vs)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % per1, per2 : percentage-represented location of two cursors
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Tmin=plot.XLim(1); Tmax=plot.XLim(2);

            Tcur1=Tmin+(Tmax-Tmin)*per1;
            Tcur2=Tmin+(Tmax-Tmin)*per2;
            
            max = 2^20;
            % Graphic Components Update
            srt_vline = line(plot, [Tcur1, Tcur1], [-max, max], 'Color', 'red', 'LineWidth', 2);
            end_vline = line(plot, [Tcur2, Tcur2], [-max, max], 'Color', 'blue', 'LineWidth', 2);
            shadedArea = patch(plot, ...
                         'XData', [Tcur1, Tcur1, Tcur2, Tcur2], ...
                         'YData', [-max, max, max, -max], ...
                         'FaceColor', 'black', 'FaceAlpha', 0.3);

            % Where it marks
            sel_idxs=find(ts>=Tcur1 & ts<=Tcur2);
            
            sel_ts=[];
            sel_vs=[];

            if ~isempty(sel_idxs)
                sel_ts=ts(sel_idxs);
                sel_vs=vs(sel_idxs);
            end
            return
        end
        %% 
        function empty=isValid_TVpair(app, ts, vs)
            if isempty(ts) && isempty(vs)
                disp('Warning: ts and vs are both emtpy');
                empty = true;
                return;
            elseif ~isempty(ts) && ~isempty(vs)
                if numel(ts)~=numel(vs)
                    error('Error: ts and vs have different length.')
                end
            else
                error('Error: Either ts or ts is empty.')
            end
            empty=false;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            availablePorts = serialportlist("available");
            app.M_PortsDropDown.Items = [app.default_port_message, availablePorts];
            app.M_PortsDropDown.Value = app.default_port_message;
        
            % Initialize timers but don't start them yet
            app.dataTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.01, ...
                                  'TimerFcn', @(src, event)acquireData(app));
            app.plotTimer = timer('ExecutionMode', 'fixedRate', 'Period', 2, ...
                                  'TimerFcn', @(src, event)updatePlot(app));

            % Monitor Init
            app.M_In_Plot.XLimMode = 'manual';
            app.M_Out_Plot.XLimMode = 'manual';
            app.M_Out_Slider.Enable=false;
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            if ~isempty(app.dataTimer)
                stop(app.dataTimer);
                delete(app.dataTimer);
            end
            if ~isempty(app.plotTimer)
                stop(app.plotTimer);
                delete(app.plotTimer);
            end
            if ~isempty(app.device)
                delete(app.device);
            end
            delete(app.UIFigure);

        end

        % Value changed function: M_PortsDropDown
        function M_PortsDropDownValueChanged(app, event)

            value = app.M_PortsDropDown.Value;
            if ~strcmp(value, app.default_port_message)
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                try
                    app.device = serialport(value, 9600);
                    configureTerminator(app.device, "CR/LF");
                    % Start timers when a valid port is selected and device is configured
                    start(app.dataTimer);
                    start(app.plotTimer);
                catch exception
                    uialert(app.UIFigure, exception.message, 'Serial Port Error');
                    app.M_PortsDropDown.Value = app.default_port_message;
                end
            else
                if ~isempty(app.device)
                    delete(app.device);
                    app.device = [];
                end
                % Stop timers if the default message is selected
                stop(app.dataTimer);
                stop(app.plotTimer);
            end

        end

        % Button pushed function: M_RUNSTOPButton
        function M_RUNSTOPButtonPushed(app, event)
            if app.isRunning % Stop
                app.M_ModeDropDown.Enable = 'off';
            else % Run
                app.M_ModeDropDown.Enable = 'on';
                app.resetMarks();
            end
            app.isRunning=~app.isRunning;
        end

        % Button pushed function: M_SAVEButton
        function M_SAVEButtonPushed(app, event)

            if isgraphics(app.vLine1, 'line') && isgraphics(app.vLine2, 'line')
                % Extract the X coordinates of the two lines
                x1 = app.vLine1.XData(1);
                x2 = app.vLine2.XData(1);
                app.M_In_tBuf
        
                % Ensure x1 is the smaller value
                if x1 > x2
                    [x1, x2] = deal(x2, x1); % Swap x1 and x2
                end
        
                % Find the indices of the data points that fall within the range [x1, x2]
                inRangeIndices = find(app.M_In_tBuf >= x1 & app.M_In_tBuf <= x2);
        
                if ~isempty(inRangeIndices)
                    % Extract the data points that fall within the range
                    filteredTimestamps = app.M_In_tBuf(inRangeIndices);
                    filteredVolts = app.M_In_vBuf(inRangeIndices);
        
                    % Call saveCSV to save the filtered data
                    app.saveCSV(filteredTimestamps, filteredVolts);
                else
                    % Handle the case where no data falls within the selected range
                    uialert(app.UIFigure, 'No data falls within the selected range.', 'Save Error');
                end
            else
                % Handle the case where one or both lines have not been placed
                uialert(app.UIFigure, 'Please select a range by placing both lines on the plot.', 'Save Error');
            end

        end

        % Button pushed function: M_RECORDButton
        function M_RECORDButtonPushed(app, event)
            if app.isRecording % Stop recording and save the data
                app.saveCSV(app.Save_tBuf, app.Save_vBuf);
                app.M_RecordLamp.Color=[0.80,0.80,0.80];
            else % Start recording
                app.M_RecordLamp.Color='red';
      
            end
            app.isRecording=~app.isRecording;
        end

        % Button pushed function: UL_SearchButton
        function UL_SearchButtonPushed(app, event)
            % Open a file open dialog allowing the user to specify a CSV file
                [fileName, path] = uigetfile({'*.csv','CSV Files (*.csv)'}, 'Select the file.');
                if isequal(fileName, 0) || isequal(path, 0)
                    disp('User clicked Cancel.');
                else
                    % Construct the full path where the file will be saved
                    fullPath = fullfile(path, fileName);
                    disp(['User selected ', fullPath, ' for playing.']);
                    
                    % Read the data from the CSV file
                    [app.UL_tBuf, app.UL_vBuf] = app.readCSV(fullPath);
            
                    % Normalize the time buffer to start from zero
                    app.UL_tBuf = app.UL_tBuf - min(app.UL_tBuf);
                    
                    % Set the axes to auto scale initially
                    app.UL_Plot.XLimMode = 'auto';
                    app.UL_Plot.YLimMode = 'auto';

                    % Plot the data
                    plot(app.UL_Plot, app.UL_tBuf, app.UL_vBuf);
                    
                    % EditField Update
                    app.UL_NumUpdate(app.UL_tBuf, app.UL_vBuf);
                    
                    % Update the file edit field with the selected file name
                    app.UL_FileEditField.Value = fileName;
                end
        end

        % Value changed function: UL_Slider
        function UL_SliderValueChanged(app, event)
            app.UL_Plot.XLimMode = 'manual';
            app.UL_Plot.YLimMode = 'manual';

            value = app.UL_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            %% Update Marks & EditVields
            app.resetMarks();
            [app.UL_srt_vline, app.UL_end_vline, app.UL_shadedArea, sel_ts, sel_vs]=app.drawMarks(per1, per2, app.UL_Plot, app.UL_tBuf, app.UL_vBuf);
            app.UL_NumUpdate(sel_ts, sel_vs);
        end

        % Button pushed function: UL_FlushButton
        function UL_FlushButtonPushed(app, event)
            app.Playback_tBuf=[];
            app.Playback_vBuf=[];
        end

        % Button pushed function: UL_UploadButton
        function UL_UploadButtonPushed(app, event)
            if isgraphics(app.shadedArea)
                disp(app.shadedArea.Xdata);
            end
        end

        % Value changed function: M_Out_Slider
        function M_Out_SliderValueChanged(app, event)
            value = app.M_Out_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            app.resetMarks();
            
            [app.M_Out_srt_vline, app.M_Out_end_vline, app.M_Out_shadedArea, ~, M_Out_sel_vs]=...
                app.drawMarks(per1, per2, app.M_Out_Plot, app.M_Out_tBuf, app.M_Out_vBuf);

            if app.M_In_Enable
                app.M_In_Slider.Value=value;
                xlim(app.M_In_Plot, app.M_Out_Plot.XLim);
                [app.M_In_srt_vline, app.M_In_end_vline, app.M_In_shadedArea, ~, M_In_sel_vs]=...
                    app.drawMarks(per1, per2, app.M_In_Plot, app.M_In_tBuf, app.M_In_vBuf);
            end

            app.M_NumUpdate(M_In_sel_vs, M_Out_sel_vs);
        end

        % Button pushed function: UL_ClearButton
        function UL_ClearButtonPushed(app, event)
            app.resetMarks();
        end

        % Value changed function: M_ModeDropDown
        function M_ModeDropDownValueChanged(app, event)
            value = app.M_ModeDropDown.Value;
            if strcmp(value, 'Record')
                app.M_In_Enable = true;
                app.M_Out_Enable = false;
                app.M_In_Slider.Enable="on";
                app.M_Out_Slider.Enable="off";

            elseif strcmp(value, 'Playback')
                app.M_In_Enable = false;
                app.M_Out_Enable = true;
                app.M_In_Slider.Enable="off";
                app.M_Out_Slider.Enable="on";
            elseif strcmp(value, 'Record & Playback')
                app.M_In_Enable = true;
                app.M_Out_Enable = true;
                app.M_In_Slider.Enable="on";
                app.M_Out_Slider.Enable="on";
            elseif strcmp(value, 'Impulse Response')
                app.M_In_Enable = true;
                app.M_Out_Enable = true;
                app.M_In_Slider.Enable="on";
                app.M_Out_Slider.Enable="on";
            else
                disp('Unknown mode selected.'); % This is optional, for unexpected values.
            end
        end

        % Value changed function: M_In_Slider
        function M_In_SliderValueChanged(app, event)
            value = app.M_In_Slider.Value;
            per1 = value(1)/100;
            per2 = value(2)/100;
            
            app.resetMarks();
            
            [app.M_In_srt_vline, app.M_In_end_vline, app.M_In_shadedArea, ~, M_In_sel_vs]=...
                app.drawMarks(per1, per2, app.M_In_Plot, app.M_In_tBuf, app.M_In_vBuf);

            if app.M_Out_Enable
                app.M_Out_Slider.Value=value;
                xlim(app.M_Out_Plot, app.M_In_Plot.XLim);
                [app.M_Out_srt_vline, app.M_Out_end_vline, app.M_Out_shadedArea, ~, M_Out_sel_vs]=...
                    app.drawMarks(per1, per2, app.M_Out_Plot, app.M_Out_tBuf, app.M_Out_vBuf);
            end

            app.M_NumUpdate(M_In_sel_vs, M_Out_sel_vs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.M_In_Plot = uiaxes(app.RecordTab);
            zlabel(app.M_In_Plot, 'Z')
            app.M_Out_Plot = uiaxes(app.RecordTab);
            zlabel(app.M_Out_Plot, 'Z')
            app.M_RecordLamp = uilamp(app.RecordTab);
            app.InputLabel = uilabel(app.RecordTab);
            app.InputLabel.Text = 'Input';
            app.M_In_PPAmpmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_In_PPAmpmVEditField.Editable = 'off';
            app.M_In_MinmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_In_MinmVEditField.Editable = 'off';
            app.M_In_MaxmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_In_MaxmVEditField.Editable = 'off';
            app.M_In_AvgmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_In_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel_2 = uilabel(app.RecordTab);
            app.MaxmVLabel_2.Text = 'Max[mV]';
            app.MinmVLabel_2 = uilabel(app.RecordTab);
            app.MinmVLabel_2.Text = 'Min[mV]';
            app.AvgmVLabel_2 = uilabel(app.RecordTab);
            app.AvgmVLabel_2.Text = 'Avg[mV]';
            app.PPmVLabel_2 = uilabel(app.RecordTab);
            app.PPmVLabel_2.Text = 'P-P[mV]';
            app.OutputLabel = uilabel(app.RecordTab);
            app.OutputLabel.Text = 'Output';
            app.M_Out_PPAmpmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_Out_PPAmpmVEditField.Editable = 'off';
            app.M_Out_MinmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_Out_MinmVEditField.Editable = 'off';
            app.M_Out_MaxmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_Out_MaxmVEditField.Editable = 'off';
            app.M_Out_AvgmVEditField = uieditfield(app.RecordTab, 'numeric');
            app.M_Out_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel_3 = uilabel(app.RecordTab);
            app.MaxmVLabel_3.Text = 'Max[mV]';
            app.MinmVLabel_3 = uilabel(app.RecordTab);
            app.MinmVLabel_3.Text = 'Min[mV]';
            app.AvgmVLabel_3 = uilabel(app.RecordTab);
            app.AvgmVLabel_3.Text = 'Avg[mV]';
            app.PPmVLabel_3 = uilabel(app.RecordTab);
            app.PPmVLabel_3.Text = 'P-P[mV]';
            app.PortsDropDownLabel = uilabel(app.RecordTab);
            app.PortsDropDownLabel.Text = 'Ports';
            app.M_PortsDropDown = uidropdown(app.RecordTab);
            app.M_PortsDropDown.ValueChangedFcn = createCallbackFcn(app, @M_PortsDropDownValueChanged, true);
            app.M_Out_Slider = uislider(app.RecordTab, 'range');
            app.M_Out_Slider.ValueChangedFcn = createCallbackFcn(app, @M_Out_SliderValueChanged, true);
            app.M_In_Slider = uislider(app.RecordTab, 'range');
            app.M_In_Slider.ValueChangedFcn = createCallbackFcn(app, @M_In_SliderValueChanged, true);
            app.M_RUNSTOPButton = uibutton(app.RecordTab, 'push');
            app.M_RUNSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @M_RUNSTOPButtonPushed, true);
            app.M_RUNSTOPButton.Text = 'RUN/STOP';
            app.M_SAVEButton = uibutton(app.RecordTab, 'push');
            app.M_SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @M_SAVEButtonPushed, true);
            app.M_SAVEButton.Text = 'SAVE';
            app.M_RECORDButton = uibutton(app.RecordTab, 'push');
            app.M_RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @M_RECORDButtonPushed, true);
            app.M_RECORDButton.Text = 'RECORD';
            app.M_SAVEButton_2 = uibutton(app.RecordTab, 'push');
            app.M_SAVEButton_2.Text = 'CLEAR';
            app.TimeScaleDropDownLabel = uilabel(app.RecordTab);
            app.TimeScaleDropDownLabel.Text = 'Time Scale';
            app.M_TimeScaleDropDown = uidropdown(app.RecordTab);
            app.M_TimeScaleDropDown.Items = {'1ms', '5ms', '10ms', '50ms', '100ms', '200ms', '500ms', '1s', '2s', '5s', '10s'};
            app.M_TimeScaleDropDown.Value = '1ms';
            app.ModeDropDownLabel = uilabel(app.RecordTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.M_ModeDropDown = uidropdown(app.RecordTab);
            app.M_ModeDropDown.Items = {'Record', 'Playback', 'Record & Playback', 'Impulse Response'};
            app.M_ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @M_ModeDropDownValueChanged, true);
            app.M_ModeDropDown.Value = 'Record';
            app.UploaderTab = uitab(app.TabGroup);
            app.UploaderTab.Title = 'Uploader';
            app.UL_Plot = uiaxes(app.UploaderTab);
            zlabel(app.UL_Plot, 'Z')
            app.FileEditFieldLabel = uilabel(app.UploaderTab);
            app.FileEditFieldLabel.Text = 'File';
            app.UL_FileEditField = uieditfield(app.UploaderTab, 'text');
            app.UL_FileEditField.Editable = 'off';
            app.UL_UploadButton = uibutton(app.UploaderTab, 'push');
            app.UL_UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UL_UploadButtonPushed, true);
            app.UL_UploadButton.Text = 'Upload';
            app.UL_SearchButton = uibutton(app.UploaderTab, 'push');
            app.UL_SearchButton.ButtonPushedFcn = createCallbackFcn(app, @UL_SearchButtonPushed, true);
            app.UL_SearchButton.Text = 'Search';
            app.UL_FlushButton = uibutton(app.UploaderTab, 'push');
            app.UL_FlushButton.ButtonPushedFcn = createCallbackFcn(app, @UL_FlushButtonPushed, true);
            app.UL_FlushButton.Text = 'Flush';
            app.UL_DurationEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_DurationEditField.Editable = 'off';
            app.tmsLabel_2 = uilabel(app.UploaderTab);
            app.tmsLabel_2.Text = 'Δt[ms]';
            app.SamplingRateLabel = uilabel(app.UploaderTab);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.UL_SamplingRateEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_SamplingRateEditField.Editable = 'off';
            app.FsHzLabel = uilabel(app.UploaderTab);
            app.FsHzLabel.Text = 'Fs[Hz]';
            app.DurationLabel = uilabel(app.UploaderTab);
            app.DurationLabel.Text = 'Duration';
            app.AmplitudeLabel = uilabel(app.UploaderTab);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.UL_PPAmpmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_PPAmpmVEditField.Editable = 'off';
            app.UL_MinmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MinmVEditField.Editable = 'off';
            app.UL_MaxmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmVEditField.Editable = 'off';
            app.UL_AvgmVEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_AvgmVEditField.Editable = 'off';
            app.MaxmVLabel = uilabel(app.UploaderTab);
            app.MaxmVLabel.Text = 'Max[mV]';
            app.MinmVLabel = uilabel(app.UploaderTab);
            app.MinmVLabel.Text = 'Min[mV]';
            app.AvgmVLabel = uilabel(app.UploaderTab);
            app.AvgmVLabel.Text = 'Avg[mV]';
            app.PPmVLabel = uilabel(app.UploaderTab);
            app.PPmVLabel.Text = 'P-P[mV]';
            app.IntervalLabel = uilabel(app.UploaderTab);
            app.IntervalLabel.Text = 'Interval';
            app.UL_MinmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MinmsEditField.Editable = 'off';
            app.UL_StdmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_StdmsEditField.Editable = 'off';
            app.UL_AvgmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_AvgmsEditField.Editable = 'off';
            app.UL_MaxmsEditField = uieditfield(app.UploaderTab, 'numeric');
            app.UL_MaxmsEditField.Editable = 'off';
            app.MaxmsLabel = uilabel(app.UploaderTab);
            app.MaxmsLabel.Text = 'Max[ms]';
            app.MinmsLabel = uilabel(app.UploaderTab);
            app.MinmsLabel.Text = 'Min[ms]';
            app.AvgmsLabel = uilabel(app.UploaderTab);
            app.AvgmsLabel.Text = 'Avg[ms]';
            app.StdmsLabel = uilabel(app.UploaderTab);
            app.StdmsLabel.Text = 'Std[ms]';
            app.UL_Slider = uislider(app.UploaderTab, 'range');
            app.UL_Slider.MajorTicks = [0 20 40 60 80 100];
            app.UL_Slider.ValueChangedFcn = createCallbackFcn(app, @UL_SliderValueChanged, true);
            app.UL_Slider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.UL_ClearButton = uibutton(app.UploaderTab, 'push');
            app.UL_ClearButton.ButtonPushedFcn = createCallbackFcn(app, @UL_ClearButtonPushed, true);
            app.UL_ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unified_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end